classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        VolSliderLabel                matlab.ui.control.Label
        VolSlider                     matlab.ui.control.Slider
        SignalDropDownLabel           matlab.ui.control.Label
        SignalDropDown                matlab.ui.control.DropDown
        WeightingSchemeDropDownLabel  matlab.ui.control.Label
        WeightingSchemeDropDown       matlab.ui.control.DropDown
        HTML                          matlab.ui.control.HTML
        RunButton                     matlab.ui.control.Button
        StartDatePickerLabel          matlab.ui.control.Label
        StartDatePicker               matlab.ui.control.DatePicker
        FeesbPEditFieldLabel          matlab.ui.control.Label
        FeesbPEditField               matlab.ui.control.NumericEditField
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        UITable                       matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        var = load('GUI/appData.mat')
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            %{
           This function is the core of the GUI, you can change parameters
            and then you need to run the function with the button.
            
            1. Extracting Parameters (except date) from User
            2. Manage Time span
            3. Compute portfolio Statistics
            4. Put statistics into the table
            5. Plot the results
            
            %}
            % Define Strategy
            IdxSignal = find(app.var.NS == app.SignalDropDown.Value);
            IdxWeight = find(app.var.NW==app.WeightingSchemeDropDown.Value);
            
            % 1. Get all parameters of the components and apply them to the GUI
            P.L = app.VolSlider.Value/app.var.V;
            P.S = app.var.S(:, :, IdxSignal);
            P.W = app.var.W(:, :, IdxWeight);
        
            % 2. Date management
            IdxDate = find(app.var.MD < app.StartDatePicker.Value, 1, 'first'); %We find the first previous monthly date
            temp = app.var.L(IdxSignal,...
                IdxWeight, ...
                IdxDate:end);
            
                %  Reshape Leverage and adapt
            P.L = P.L*reshape(temp, [size(temp,3) size(temp,2)*size(temp,1)]);
            clear temp
            
            % 3. Get Portfolio Statistics
            [P.R, P.CumR, P.Stats] = ...
                PortfolioStatistics(app.var.MR(IdxDate:end,:),...
                P.W(IdxDate:end,:).*P.S(IdxDate:end,:),...
                P.L,...
                app.FeesbPEditField.Value/10000);
            
            % 4. Give value to table (ui Object)
            app.UITable.Data = [P.Stats.Properties.RowNames,P.Stats(:,:)];
            
            % 5. Plot values
            plot(app.UIAxes,app.var.MD(IdxDate+1:end),P.CumR)
            title(app.UIAxes, sprintf('%s signal and %s weights'...
                ,app.SignalDropDown.Value , app.WeightingSchemeDropDown.Value))
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes,'Cumulative Return')
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.VolSliderLabel = uilabel(app.LeftPanel);
            app.VolSliderLabel.Text = 'Vol. %';
            app.VolSlider = uislider(app.LeftPanel);
            app.VolSlider.Value = 10;
            app.SignalDropDownLabel = uilabel(app.LeftPanel);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.LeftPanel);
            app.SignalDropDown.Items = {'S-T Momentum', 'L-T Momentum', '9 - 12', 'MA', 'SSA', 'EWMA', 'SVM'};
            app.SignalDropDown.Value = 'S-T Momentum';
            app.WeightingSchemeDropDownLabel = uilabel(app.LeftPanel);
            app.WeightingSchemeDropDownLabel.Text = 'Weighting Scheme';
            app.WeightingSchemeDropDown = uidropdown(app.LeftPanel);
            app.WeightingSchemeDropDown.Items = {'EW', 'Vol. Parity', 'Risk Parity'};
            app.WeightingSchemeDropDown.Value = 'EW';
            app.HTML = uihtml(app.LeftPanel);
            app.HTML.HTMLSource = '<h2>Trend Following</h2><p>This GUI allows you to choose some signals, Weighting Scheme, Volatility target and starting date in order to see how the strategy would have perform.</p>';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run !';
            app.StartDatePickerLabel = uilabel(app.LeftPanel);
            app.StartDatePickerLabel.Text = {'Start'; ''};
            app.StartDatePicker = uidatepicker(app.LeftPanel);
            app.StartDatePicker.Limits = [datetime([2007 8 13]) datetime([2020 8 31])];
            app.StartDatePicker.Value = datetime([2007 8 13]);
            app.FeesbPEditFieldLabel = uilabel(app.LeftPanel);
            app.FeesbPEditFieldLabel.Text = 'Fees (bP)';
            app.FeesbPEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FeesbPEditField.Value = 10;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Measure'; 'Value'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end