classdef GUI_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        RunButton               matlab.ui.control.Button
        FeesbPEditFieldLabel    matlab.ui.control.Label
        FeesbPEditField         matlab.ui.control.NumericEditField
        VolTargetSliderLabel    matlab.ui.control.Label
        VolTargetSlider         matlab.ui.control.Slider
        WeightingDropDownLabel  matlab.ui.control.Label
        WeightingDropDown       matlab.ui.control.DropDown
        SignalDropDownLabel     matlab.ui.control.Label
        SignalDropDown          matlab.ui.control.DropDown
        HTML                    matlab.ui.control.HTML
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        UITable                 matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    % Loading preprocessed data
    properties (Access = public)
        var = load('GUI/appData.mat')
        
    %{ 
      var is like a structure with : 
        
        var.S = NX3 signal matrix
        var.W = NX3 weight matrix
        var.L = NX3X3 leverage Matrix
        var.minSize = number of days
        var.D = Date
        var.V = volatility target
        var.NS = Names of the signals
        var.NW = Names of the weights
        var.R = Returns of the assets
        
        %}
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % We directly display the results of the default allocation
        %{
        *****************************************************************
        Parameters Extraction
        *****************************************************************
        %}
        % Get Weight and signal index
        idxW = app.var.GUI.NW == app.WeightingDropDown.Value;
        idxS = app.var.GUI.NS == app.SignalDropDown.Value;
        
        % Extract corresponding weights and signal
        W = app.var.GUI.W(:, :, idxS);
        S = app.var.GUI.S(:, :, idxW);
        
        % Leverage on leverage(our position is already levered to have 10%)
        L = app.var.GUI.L(:, idxS, idxW) * app.VolTargetSlider.Value / (app.var.GUI.V*100);
        
        % Compute Portfolio Statistics
        [~, CumR, Stats] = PortfolioStatistics(app.var.GUI.R,W.*S, L, ...
            app.FeesbPEditField.Value/10000);
        
         %{
        *****************************************************************
        Displaying results
        *****************************************************************
        %}   
            
        
        % 4. Give value to table (ui Object)
        app.UITable.Data = [Stats.Properties.RowNames,Stats(:,:)];
        
        % 5. Plot values
        plot(app.UIAxes,app.var.GUI.D(2:end),CumR)
        title(app.UIAxes, sprintf('%s signal and %s weights'...
            ,app.SignalDropDown.Value , app.WeightingDropDown.Value))        
        
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        %{ 
         The core of the GUI :
            - Parameters are updated by the user but it does not uptade the
            figures.
            - This function extract the necessary objects properties and
            compute the values to display the results
        %}
        
        %{
        *****************************************************************
        Parameters Extraction
        *****************************************************************
        %}
        % Get Weight and signal index
        idxW = app.var.GUI.NW == app.WeightingDropDown.Value;
        idxS = app.var.GUI.NS == app.SignalDropDown.Value;
        
        % Extract corresponding weights and signal
        W = app.var.GUI.W(:, :, idxS);
        S = app.var.GUI.S(:, :, idxW);
        
        % Leverage on leverage(our position is already levered to have 10%)
        L = app.var.GUI.L(:, idxS, idxW) * app.VolTargetSlider.Value / (app.var.GUI.V*100);
        
        % Compute Portfolio Statistics
        [~, CumR, Stats] = PortfolioStatistics(app.var.GUI.R,W.*S, L, ...
            app.FeesbPEditField.Value/10000);
        
         %{
        *****************************************************************
        Displaying results
        *****************************************************************
        %}   
            
        
        % 4. Give value to table (ui Object)
        app.UITable.Data = [Stats.Properties.RowNames,Stats(:,:)];
        
        % 5. Plot values
        plot(app.UIAxes,app.var.GUI.D(2:end),CumR)
        title(app.UIAxes, sprintf('%s signal and %s weights'...
            ,app.SignalDropDown.Value , app.WeightingDropDown.Value))

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run !';
            app.FeesbPEditFieldLabel = uilabel(app.LeftPanel);
            app.FeesbPEditFieldLabel.Text = 'Fees (bP)';
            app.FeesbPEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FeesbPEditField.Value = 10;
            app.VolTargetSliderLabel = uilabel(app.LeftPanel);
            app.VolTargetSliderLabel.Text = 'Vol. Target';
            app.VolTargetSlider = uislider(app.LeftPanel);
            app.VolTargetSlider.Limits = [5 20];
            app.VolTargetSlider.Value = 10;
            app.WeightingDropDownLabel = uilabel(app.LeftPanel);
            app.WeightingDropDownLabel.Text = 'Weighting';
            app.WeightingDropDown = uidropdown(app.LeftPanel);
            app.WeightingDropDown.Items = {'Equally Weighted', 'Volatility Parity', 'Risk Parity'};
            app.WeightingDropDown.Value = 'Equally Weighted';
            app.SignalDropDownLabel = uilabel(app.LeftPanel);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.LeftPanel);
            app.SignalDropDown.Items = {'EWMA', 'SSA', 'SVM'};
            app.SignalDropDown.Value = 'SVM';
            app.HTML = uihtml(app.LeftPanel);
            app.HTML.HTMLSource = '<h2>Trend Following</h2>This GUI backtest a trend-following strategy for which you can choose a signal, the weighting scheme, a volatility target and the level of fees.</p>';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'Cumulative Return (base = 100)')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Statistics'; 'Value'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end