classdef trendFollowingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        RunButton               matlab.ui.control.Button
        VolatilityLabel         matlab.ui.control.Label
        VolatilitySlider        matlab.ui.control.Slider
        StartDatePickerLabel    matlab.ui.control.Label
        StartDatePicker         matlab.ui.control.DatePicker
        SignalDropDownLabel     matlab.ui.control.Label
        SignalDropDown          matlab.ui.control.DropDown
        WeightsDropDownLabel    matlab.ui.control.Label
        WeightsDropDown         matlab.ui.control.DropDown
        HTML                    matlab.ui.control.HTML
        HTML2                   matlab.ui.control.HTML
        HTML3                   matlab.ui.control.HTML
        FeesbPEditField_2Label  matlab.ui.control.Label
        FeesbPEditField         matlab.ui.control.NumericEditField
        EndDatePickerLabel      matlab.ui.control.Label
        EndDatePicker           matlab.ui.control.DatePicker
        RightPanel              matlab.ui.container.Panel
        Image                   matlab.ui.control.Image
        UITable                 matlab.ui.control.Table
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        
        % Load Preprocessed Data
        var = load('appData.mat')
        
    end
    
    methods (Access = private)
        
        function stats = statsFcn(~, returnStrat)

            
            % Computing the annualized mean
            Mean = prod(returnStrat+1)^(1/(length(returnStrat)/12))-1;
            
            % Computing the annualized volatility
            Vol = std(returnStrat)*sqrt(12);
            
            % Computing the Sharpe Ratios
            Sharpe = SharpeRatio(returnStrat,0.01);
            
            % Computing the Maximum DrawDown
            MaxDrawDown = MDD(returnStrat);
            
            % Computing the Calmar Ratio
            Calmar = Mean/MaxDrawDown;
            
            % Computing the Kurtosis
            Kurt = kurtosis(returnStrat);
            
            % Computing the Skewness
            Skew = skewness(returnStrat);
            
            stats = [Mean;Vol;Kurt;Skew;Sharpe;Calmar;MaxDrawDown];

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %{
           
            This function display initial results for the default choice
            when the components are initialized. It allows to display
            results at the creation of the app.
            
            %}
 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {459, 459};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
      %{

        This is the core of the app, we are not computing the results
        dynamically when the user choose the parameters but when he
        click the "Run" button. Therefore all the computations are
        performed on this function.
        
        %}
            
      %{
        *****************************************************************
        Parameters Extraction
        *****************************************************************
        %}
            
        % Get Weight and signal index
        idxW = app.var.weightNames == app.WeightsDropDown.Value;
        idxS = app.var.signalNames == app.SignalDropDown.Value;           
            

        % Compute Statistics
        leverageAPP = (app.VolatilitySlider.Value/100)/app.var.initialVolatility;
        feeChange = 1-(app.FeesbPEditField.Value-app.var.defaultFee)/10000;
        totalChange = leverageAPP .* feeChange;
        disp(totalChange)
        
        % Handling Date
        [~,ind1] = min(abs(datenum(app.var.dateMonthGui)-datenum(app.StartDatePicker.Value)));
        [~,ind2] = min(abs(datenum(app.var.dateMonthGui)-datenum(app.EndDatePicker.Value)));
        temp_cum = app.var.cumulativeReturn;
        temp_r = app.var.returnMonth;
        app.var.cumulativeReturn = app.var.cumulativeReturn(ind1:end,:, :);
        app.var.returnMonth =  app.var.returnMonth(ind1:ind2, idxS, idxW);

        
        % From cumulative to indivual and back
        returnStrat = feeChange*(1+leverageAPP*app.var.returnMonth)-1;
        cumulativeReturn_p = cumprod(1 + returnStrat).*100;
        
        
        % Compute statisitcs
        stats = statsFcn(app, returnStrat);
        stats = cat(1, stats(1:4), app.var.turnover(idxS, idxW), stats(5:end));
       
        
          %{
        *****************************************************************
        Displaying results
        *****************************************************************
        %}   


        % 4. Give value to table (ui Object)
        app.UITable.Data = [string(app.var.statsName(1:end-1)),stats];
        
        % 5. Plot values
        plot(app.UIAxes,app.var.dateMonthGui(ind1:ind2),cumulativeReturn_p)
        title(app.UIAxes, sprintf('%s signal and %s weights'...
            ,app.SignalDropDown.Value , app.WeightsDropDown.Value))             
        
        app.var.cumulativeReturn = temp_cum;
        app.var.returnMonth = temp_r;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run !';
            app.VolatilityLabel = uilabel(app.LeftPanel);
            app.VolatilityLabel.Text = 'Volatility(%)';
            app.VolatilitySlider = uislider(app.LeftPanel);
            app.VolatilitySlider.Limits = [5 20];
            app.VolatilitySlider.Value = 10;
            app.StartDatePickerLabel = uilabel(app.LeftPanel);
            app.StartDatePickerLabel.Text = 'Start';
            app.StartDatePicker = uidatepicker(app.LeftPanel);
            app.StartDatePicker.Limits = [datetime([2007 7 2]) datetime([2020 8 31])];
            app.StartDatePicker.Value = datetime([2007 7 2]);
            app.SignalDropDownLabel = uilabel(app.LeftPanel);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.LeftPanel);
            app.SignalDropDown.Items = {'Momentum S-T', 'Momentum L-T', 'Moving Average', 'EWMA', 'SSA', 'SVM'};
            app.SignalDropDown.Value = 'Momentum S-T';
            app.WeightsDropDownLabel = uilabel(app.LeftPanel);
            app.WeightsDropDownLabel.Text = 'Weights';
            app.WeightsDropDown = uidropdown(app.LeftPanel);
            app.WeightsDropDown.Items = {'Equally Weighted', 'Volatility Parity', 'Risk Parity'};
            app.WeightsDropDown.Value = 'Equally Weighted';
            app.HTML = uihtml(app.LeftPanel);
            app.HTML.HTMLSource = '<b> Parameters </b>';
            app.HTML2 = uihtml(app.LeftPanel);
            app.HTML2.HTMLSource = '<b> Strategy </b>';
            app.HTML3 = uihtml(app.LeftPanel);
            app.HTML3.HTMLSource = '<b> Trend Following App </b> <br>This application backtest a trend-following strategy with the choice of the user.<br>The starting date cannot be earlier that 02.07.2007.<br> You can choose the signal and the weighting scheme as well as the volatility and the level of fees.';
            app.FeesbPEditField_2Label = uilabel(app.LeftPanel);
            app.FeesbPEditField_2Label.Text = 'Fees (bP)';
            app.FeesbPEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FeesbPEditField.Value = 10;
            app.EndDatePickerLabel = uilabel(app.LeftPanel);
            app.EndDatePickerLabel.Text = 'End';
            app.EndDatePicker = uidatepicker(app.LeftPanel);
            app.EndDatePicker.Limits = [datetime([2007 2 7]) datetime([2020 8 31])];
            app.EndDatePicker.Value = datetime([2020 8 31]);
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'logoHEC.png';
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Statistics'; 'Strategy'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'Cumultative Return (base = 100)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trendFollowingGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end